classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        Button           matlab.ui.control.Button
        EditField3Label  matlab.ui.control.Label
        result           matlab.ui.control.NumericEditField
        EditField4Label  matlab.ui.control.Label
        val1             matlab.ui.control.NumericEditField
        EditField5Label  matlab.ui.control.Label
        val2             matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Data % Gögn úr excel skjali
    end

    methods (Access = public)
        % Reiknar út meanReturn, StdDev og Covariance Matrix fyrir object Data
        function [meanReturn,StdDev,covMatrix] = assets(obj)
            meanReturn = (mean(obj.Data)')/100;
            StdDev = (std(obj.Data))/100;
            covMatrix = (cov(obj.Data))/100;
        end
    end


    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            fileName = uigetfile('*.xlsx');
            [num,txt,raw] = xlsread(fileName);
            app.Data = num;
            
            [meanReturn,StdDev,covMatrix] = assets(app);
            disp(meanReturn);
            disp(StdDev);
            disp(covMatrix);
            
            %disp(meanReturn)
            %disp(stdDev(app))
            %disp(covMatrix(app))

            %app.result.Value = j;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.result = uieditfield(app.UIFigure, 'numeric');
            app.EditField4Label = uilabel(app.UIFigure);
            app.EditField4Label.Text = 'Edit Field4';
            app.val1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5Label = uilabel(app.UIFigure);
            app.EditField5Label.Text = 'Edit Field5';
            app.val2 = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end